classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BarMaster3000UIFigure  matlab.ui.Figure
        image_stand_by         matlab.ui.control.Image
        drink_image            matlab.ui.control.Image
        arrow_r                matlab.ui.control.Button
        arrow_l                matlab.ui.control.Button
        end_prog               matlab.ui.control.Button
        mix                    matlab.ui.control.Button
        ruleta                 matlab.ui.control.Button
        personalised           matlab.ui.control.Button
        Image                  matlab.ui.control.Image
    end

    
    properties (Access = public)
         current_drink = "Cuba_libre" % Holds the info about the currently selected drink
         drinks = ["Cuba_libre", "Gin_tonic", "Vodka_dzus"] % Vector of all drinks
         drink_array = ["/Pictures/Drinks/Cuba_libre.jpg",
             "/Pictures/Drinks/Gin_tonic.jpg",
             "/Pictures/Drinks/Vodka_dzus.jpg"] % Vector of relative locations of drink pictures
         gui_ready = 0
         command = ""
         current_app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            app.current_app = app;
        end

        % Button pushed function: end_prog
        function end_progButtonPushed(app, event)
            command = "STOP APP";
            app.delete;
            
        end

        % Button pushed function: arrow_r
        function arrow_rButtonPushed(app, event)
            app.drink_array = circshift(app.drink_array, 1);
                app.drinks = circshift(app.drinks, 1);
                app.current_drink = app.drinks(1);
                app.drink_image.ImageSource = imread(pwd + "\UI" + app.drink_array(1));
                

        end

        % Button pushed function: arrow_l
        function arrow_lButtonPushed(app, event)
            app.drink_array = circshift(app.drink_array, -1);
                app.drinks = circshift(app.drinks, -1);
                app.current_drink = app.drinks(1);
                app.drink_image.ImageSource = imread(pwd + "\UI" + app.drink_array(1));
        end

        % Button pushed function: mix
        function mixButtonPushed(app, event)
            disp("We are mixing " + app.current_drink )
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BarMaster3000UIFigure = uifigure('Visible', 'off');
            app.BarMaster3000UIFigure.Name = 'BarMaster 3000';
            app.BarMaster3000UIFigure.Icon = fullfile(pathToMLAPP, 'Pictures', 'Icon.png');
            app.Image = uiimage(app.BarMaster3000UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Pictures', 'Background_img.jpg');
            app.personalised = uibutton(app.BarMaster3000UIFigure, 'push');
            app.personalised.Text = 'Vlastní';
            app.ruleta = uibutton(app.BarMaster3000UIFigure, 'push');
            app.ruleta.Text = 'Ruleta';
            app.mix = uibutton(app.BarMaster3000UIFigure, 'push');
            app.mix.ButtonPushedFcn = createCallbackFcn(app, @mixButtonPushed, true);
            app.mix.Text = 'Namíchat';
            app.end_prog = uibutton(app.BarMaster3000UIFigure, 'push');
            app.end_prog.ButtonPushedFcn = createCallbackFcn(app, @end_progButtonPushed, true);
            app.end_prog.Text = 'Ukončit';
            app.arrow_l = uibutton(app.BarMaster3000UIFigure, 'push');
            app.arrow_l.ButtonPushedFcn = createCallbackFcn(app, @arrow_lButtonPushed, true);
            app.arrow_l.Text = '<';
            app.arrow_r = uibutton(app.BarMaster3000UIFigure, 'push');
            app.arrow_r.ButtonPushedFcn = createCallbackFcn(app, @arrow_rButtonPushed, true);
            app.arrow_r.Text = '>';
            app.drink_image = uiimage(app.BarMaster3000UIFigure);
            app.drink_image.ImageSource = fullfile(pathToMLAPP, 'Pictures', 'Drinks', 'Cuba_libre.jpg');
            app.image_stand_by = uiimage(app.BarMaster3000UIFigure);
            app.image_stand_by.Visible = 'off';
            app.image_stand_by.ImageSource = fullfile(pathToMLAPP, 'Pictures', 'Stand_by.png');
            % Show the figure after all components are created
            app.BarMaster3000UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BarMaster3000UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BarMaster3000UIFigure)
        end
    end
end